classdef noise_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        AmplitudmxSliderLabel  matlab.ui.control.Label
        AmplitudmxSlider       matlab.ui.control.Slider
        TipoderuidoDropDown    matlab.ui.control.DropDown
        NuevamuestraButton     matlab.ui.control.Button
        GenerarButton          matlab.ui.control.Button
        GenerarruidoLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        t % Array de tiempo
        current_data % Muestra actual
        fs % Frecuencia de muestreo
        type % Tipo de ruido actual
        amp % Amplitud
        CallingApp % App principal
    end
    
    methods (Access = private)
        
        % Genera un array de fs muestras de ruido blanco
        % Con valores entre -1 y 1
        function wn = whiteNoise(~,fs)
            wn = randn(1,fs);
            wn = wn./max(abs(wn));
        end
        
        % Lo mismo, con ruido rosa
        function pn = pinkNoise(~,fs)
            pn = pinknoise(fs)';
            pn = pn./max(abs(pn));
        end
        
        function resizeWindow(app)
            % Dimensiones de las ventanas en una pantalla de 1280x1024
            originalw = 640;
            originalh = 480;
            
            % Dimensiones de la nueva pantalla
            ssize = get(0,'ScreenSize');
            screenw = ssize(3);
            screenh = ssize(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalw*screenw/1280);
            newh = round(originalh*screenh/1024);
            
            % Centrar la ventana en la pantalla
            hpos = screenw/2-neww/2;
            vpos = screenh/2-newh/2;
            
            app.UIFigure.Position = [hpos vpos neww newh];
        end
        
        function resizeelement(~,element)
            
            originalhpos = element.Position(1);
            originalvpos = element.Position(2);
            originalw = element.Position(3);
            originalh = element.Position(4);
            
            % Dimensiones de la nueva pantalla
            ssize = get(0,'ScreenSize');
            screenw = ssize(3);
            screenh = ssize(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalw*screenw/1280);
            newh = round(originalh*screenh/1024);
            
            newhpos = round(originalhpos*screenw/1280);
            newvpos = round(originalvpos*screenh/1024);
            
            element.Position = [newhpos newvpos neww newh];
            
            element.FontSize = round(element.FontSize*(screenw*screenh)/(1280*1024));
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp = mainApp;
            
            app.resizeWindow();
            for i = 1:length(app.UIFigure.Children)
                app.resizeelement(app.UIFigure.Children(i));
            end
            
            % Inicializa las variables
            app.fs = 48000;
            app.t = linspace(0,1,app.fs);
            app.type = app.TipoderuidoDropDown.Value;
            app.amp = 1;
            
            % Genera muestra predeterminada y la dibuja
            app.current_data = app.whiteNoise(app.fs);
            
            plot(app.UIAxes,app.t,app.current_data)
        end

        % Value changed function: TipoderuidoDropDown
        function TipoderuidoDropDownValueChanged(app, event)
            % Cambia el tipo de ruido y crea una nueva muestra
            app.type = app.TipoderuidoDropDown.Value;
            
            if strcmp(app.type,'Ruido blanco')
                app.current_data = app.amp*app.whiteNoise(app.fs);
            else
                app.current_data = app.amp*app.pinkNoise(app.fs);
            end
            
            plot(app.UIAxes,app.t,app.current_data)
        end

        % Value changed function: AmplitudmxSlider
        function AmplitudmxSliderValueChanged(app, event)
            % Cambia la amplitud seleccionada y modifica la amplitud de 
            % la muestra actual
            app.amp = round(app.AmplitudmxSlider.Value,2);
            
            app.current_data = app.current_data.*(app.amp/max(abs(app.current_data)));
            
            plot(app.UIAxes,app.t,app.current_data)
        end

        % Button pushed function: NuevamuestraButton
        function NuevamuestraButtonPushed(app, event)
            % Genera nueva muestra con los valores seleccionados
            
            if strcmp(app.type,'Ruido blanco')
                app.current_data = app.amp*app.whiteNoise(app.fs);
            else
                app.current_data = app.amp*app.pinkNoise(app.fs);
            end
            
            plot(app.UIAxes,app.t,app.current_data)
        end

        % Button pushed function: GenerarButton
        function GenerarButtonPushed(app, event)
            % Envía la señal a la aplicación principal y cierra
            
            updatePlot(app.CallingApp,app.current_data,app.t);
            
            % Habilita los menús de la aplicación principal
            app.CallingApp.SignalVisualizerMenu.Enable = 'on';
            app.CallingApp.GenerarMenu.Enable = 'on';
            
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Al cerrar la ventana pulsando el botón
            
            % Habilita los menús de la aplicación principal
            app.CallingApp.SignalVisualizerMenu.Enable = 'on';
            app.CallingApp.GenerarMenu.Enable = 'on';
            
            % Habilita el botón play sólo si ya hay una señal previamente
            % cargada
            if isempty(app.CallingApp.signal) == 0 
                app.CallingApp.PlayButton.Enable = 'on';
            end
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Tiempo (s)')
            ylabel(app.UIAxes, 'Amplitud')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [-1 1];
            app.AmplitudmxSliderLabel = uilabel(app.UIFigure);
            app.AmplitudmxSliderLabel.Text = 'Amplitud máx.';
            app.AmplitudmxSlider = uislider(app.UIFigure);
            app.AmplitudmxSlider.Limits = [0 1];
            app.AmplitudmxSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.AmplitudmxSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudmxSliderValueChanged, true);
            app.AmplitudmxSlider.Value = 1;
            app.TipoderuidoDropDown = uidropdown(app.UIFigure);
            app.TipoderuidoDropDown.Items = {'Ruido blanco', 'Ruido rosa'};
            app.TipoderuidoDropDown.ValueChangedFcn = createCallbackFcn(app, @TipoderuidoDropDownValueChanged, true);
            app.TipoderuidoDropDown.Value = 'Ruido blanco';
            app.NuevamuestraButton = uibutton(app.UIFigure, 'push');
            app.NuevamuestraButton.ButtonPushedFcn = createCallbackFcn(app, @NuevamuestraButtonPushed, true);
            app.NuevamuestraButton.Text = 'Nueva muestra';
            app.GenerarButton = uibutton(app.UIFigure, 'push');
            app.GenerarButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonPushed, true);
            app.GenerarButton.Text = 'Generar';
            app.GenerarruidoLabel = uilabel(app.UIFigure);
            app.GenerarruidoLabel.Text = 'Generar ruido';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = noise_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end